classdef AudioEqualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        DDownSoundChoice  matlab.ui.control.DropDown
        InputSoundLabel   matlab.ui.control.Label
        Hz200HzLabel      matlab.ui.control.Label
        Slider00200Hz     matlab.ui.control.Slider
        BtnPlay           matlab.ui.control.Button
        Hz750HzLabel_2    matlab.ui.control.Label
        Slider00750Hz     matlab.ui.control.Slider
        Hz3kHzLabel       matlab.ui.control.Label
        Slider03000Hz     matlab.ui.control.Slider
        kHz10kHzLabel     matlab.ui.control.Label
        Slider10000Hz     matlab.ui.control.Slider
        kHz25kHzLabel     matlab.ui.control.Label
        Slider25000Hz     matlab.ui.control.Slider
        ResetButton       matlab.ui.control.Button
        SpectrumChart     matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
               
        
        function Equalize(app)
            
            A = 200;
            B = 750;
            C = 3000;
            D = 10000;
            
            %A1 = 75; % 1/A1 drives ~ the filter cutoff normalized frequency (signal frequency / sampling frequency)
            % example: 75 -> 1 / 75 * 44.1kHz => 600Hz
            %           9 -> 1 / 9  * 44.1kHz => 4900Hz

            
            % Getting the individual gains
            
            GA = power(10,app.Slider00200Hz.Value/20) ;
            GB = power(10,app.Slider00750Hz.Value/20);
            GC = power(10,app.Slider03000Hz.Value/20);
            GD = power(10,app.Slider10000Hz.Value/20);
            GE = power(10,app.Slider25000Hz.Value/20);
            
            %setting a frame length greater than A1
            frame_len = 150;
            Fs = 41000; %frequency in Hz, global variable and 44.1kHx for self-generated samples
            audio_duration = 1; % in seconds
 
            % calculate DRRS base values
            % this formula is Fs/(2*cutoffFrequency)
            % this also enures that the base values are odd
            A1 = round((Fs/(2*A)-1)/2)*2+1;
            A2 = round((A1/1.5-1)/2)*2+1;
            B1 = round((Fs/(2*B)-1)/2)*2+1;
            B2 = round((B1/1.5-1)/2)*2+1;
            C1 = round((Fs/(2*C)-1)/2)*2+1;
            C2 = round((C1/1.5-1)/2)*2+1;
            D1 = round((Fs/(2*D)-1)/2)*2+1;
            D2 = round((D1/1.5-1)/2)*2+1;
            
            loadFile = false;
            startFreq = 0;
            coefFreq = 0;
            
            % Global declaration
            ip = zeros(1:10);
            
            switch app.DDownSoundChoice.Value
                case 1
                    startFreq = 50;
                    coefFreq = 1.135;
                    number_of_samples = 44100;
                case 2
                    startFreq = 50;
                    coefFreq = 1.05;
                case 3
                    startFreq = 1000;
                    coefFreq = 1.05;
                case 4
                    startFreq = 5000;
                    coefFreq = 1.05;
                case 5
                    loadFile = true;
                    
            end
            
            if ( loadFile == true )
                %read in audio file mono at 44.1kHz

                          
                % Display uigetfile dialog
                filterspec = {'*.wav','All WAV Files'};
                [f, p] = uigetfile(filterspec);
                
                % Make sure user didn't cancel uigetfile dialog
                if (ischar(p))
                   fname = [p f];
                else
                   return;
                end
                
                [ip,Fs] = audioread(fname);
                [m,n]=size(ip);
                if (n == 2) 
                    %stereo file
                    source=ip;
                    ip=0.5*(source(:,1)+source(:,2));
                    ip=transpose(ip);
                    ip=ip(44100*40+1:44100*45);
                end
                
                numFrames = floor(length(ip)/frame_len);
                number_of_samples = numFrames*frame_len;
                ip = ip(1:number_of_samples);
                audio_duration = numFrames*frame_len / Fs;
                
                maxip = max(ip,[],'all');
                minip = min(ip,[],'all');
                
                maxGain = max([GA,GB]);
                
                newmax = abs(maxip) * maxGain;
                newmin = abs(minip) * maxGain;
                
                
                if (newmax > 1 || newmin > 1 )
                    % Normalizing the gains to avoid distortion
                    GA = GA/maxGain;
                    GB = GB/maxGain;
                    GC = GC/maxGain;
                    GD = GD/maxGain;
                    GE = GE/maxGain;
                end
            else

                Fs = 44100; %frequency in Hz
                audio_duration = 1; % in seconds
                number_of_samples = audio_duration * Fs;
                numFrames = number_of_samples / frame_len;
                
                % Building the list of frequency making up the input signal,
                %  and the input signal
                
                ip = zeros(1, number_of_samples );
                
                freqs = zeros(1, 50);
                
                for freqid = 1:1:50
                    curfreq=startFreq*power(coefFreq,freqid-1);
                    freqs(freqid)=curfreq;
                    for recId = 1:1:number_of_samples
                        ip(recId) = ip(recId) + sin(2*pi*curfreq/44100*recId + curfreq) / 50;
                    end
                end
            end
            
            % setting up the output vectors for the filters
            a1 = zeros(1, number_of_samples);
            a2 = zeros(1, number_of_samples);
            a3 = zeros(1, number_of_samples);
            a4 = zeros(1, number_of_samples);
            a5 = zeros(1, number_of_samples);
            a6 = zeros(1, number_of_samples);
            a7 = zeros(1, number_of_samples);
            
            b1 = zeros(1, number_of_samples);
            b2 = zeros(1, number_of_samples);
            b3 = zeros(1, number_of_samples);
            b4 = zeros(1, number_of_samples);
            b5 = zeros(1, number_of_samples);
            b6 = zeros(1, number_of_samples);
            b7 = zeros(1, number_of_samples);
            b8 = zeros(1, number_of_samples);
            
            c1 = zeros(1, number_of_samples);
            c2 = zeros(1, number_of_samples);
            c3 = zeros(1, number_of_samples);
            c4 = zeros(1, number_of_samples);
            c5 = zeros(1, number_of_samples);
            c6 = zeros(1, number_of_samples);
            c7 = zeros(1, number_of_samples);
            
            d2 = zeros(1, number_of_samples);
            d3 = zeros(1, number_of_samples);
            d4 = zeros(1, number_of_samples);
            d5 = zeros(1, number_of_samples);
            d6 = zeros(1, number_of_samples);
            d7 = zeros(1, number_of_samples);
            
            e4 = zeros(1, number_of_samples);
            e6 = zeros(1, number_of_samples);
            e7 = zeros(1, number_of_samples);
            
            
            finalOutput = zeros(1, number_of_samples);
            
            
            for frameId = 1:1:numFrames %iterate through frames
                frameStartsAt = (frameId-1)*frame_len; %start position of current frame
                for recIdInFrame = 1:1:frame_len %iterate through values in current frame
                    recId = frameStartsAt + recIdInFrame; %absolute value index
                    if frameId == 1 %avoid errors processing first frame (no history available)           
                        a1(recId) = ip(recId);
                        a2(recId) = ip(recId);
                        a3(recId) = ip(recId);
                        a4(recId) = ip(recId);
                        a5(recId) = ip(recId);
                        a6(recId) = ip(recId);
                        a7(recId) = ip(recId);
                        b1(recId) = ip(recId);
                        b2(recId) = ip(recId);
                        b3(recId) = ip(recId);
                        b4(recId) = ip(recId);
                        b5(recId) = ip(recId);
                        b6(recId) = ip(recId);
                        b7(recId) = ip(recId);
                        b8(recId) = ip(recId);
                        c1(recId) = ip(recId);
                        c2(recId) = ip(recId);
                        c3(recId) = ip(recId);
                        c4(recId) = ip(recId);
                        c5(recId) = ip(recId);
                        c6(recId) = ip(recId);
                        c7(recId) = ip(recId);
                        d2(recId) = ip(recId);
                        d3(recId) = ip(recId);
                        d4(recId) = ip(recId);
                        d5(recId) = ip(recId);
                        d6(recId) = ip(recId);
                        d7(recId) = ip(recId);
                        e4(recId) = ip(recId);
                        e6(recId) = ip(recId);
                        e7(recId) = ip(recId);
                       
                        
                    else %determining the output value after the first frame filter
                        a1(recId) = (a1(recId-1) + ip(recId) - ip(recId - A1));
                        a2(recId) = (a2(recId-1) + a1(recId) - a1(recId - A2));
                        a3(recId) = (a2(recId-(B1+B2)/2+1));
                        a4(recId) = (a3(recId-(C1+C2)/2+1));
                        a5(recId) = (a4(recId-(D1+D2)/2+1));
                        a6(recId) = (a5(recId)/(A1*A2));
                        
                        b1(recId) = (ip(recId-(A1+A2)/2+1));
                        b2(recId) = (b2(recId-1) + b1(recId) - b1(recId - B1));
                        b3(recId) = (b3(recId-1) + b2(recId) - b2(recId - B2));
                        b4(recId) = (b3(recId-(C1+C2)/2+1));
                        b5(recId) = (b4(recId-(D1+D2)/2+1));
                        b6(recId) = (b5(recId)/(B1*B2));
                        
                        c1(recId) = (b1(recId-(B1+B2)/2+1));
                        c2(recId) = (c2(recId-1) + c1(recId) - c1(recId - C1));
                        c3(recId) = (c3(recId-1) + c2(recId) - c2(recId - C2));
                        c4(recId) = (c3(recId-(D1+D2)/2+1));
                        c5(recId) = (c4(recId)/(C1*C2));
                        
                        d2(recId) = (c1(recId-(C1+C2)/2+1));
                        d3(recId) = (d3(recId-1) + d2(recId) - d2(recId - D1));
                        d4(recId) = (d4(recId-1) + d3(recId) - d3(recId - D2));
                        d5(recId) = (d4(recId)/(D1*D2));
                        
                        e4(recId) = (d2(recId-(D1+D2)/2+1));
                        
                        b7(recId) = b6(recId) - a6(recId);
                        c6(recId) = c5(recId) - b6(recId);
                        d6(recId) = d5(recId) - c5(recId);
                        e6(recId) = e4(recId) - d5(recId);
                        
                        a7(recId) = GA*a6(recId);
                        b8(recId) = GB*b7(recId);
                        c7(recId) = GC*c6(recId);
                        d7(recId) = GD*d6(recId);
                        e7(recId) = GE*e6(recId);
                        
                        finalOutput(recId) = a7(recId) + b8(recId) + c7(recId) + d7(recId) + e7(recId);
                    end
                end
            end
            
            
            nf=2000; %number of points in DTFT

            fftip = fft( ip ,nf);
            %ffta7 = fft(a7, nf);
            %fftb8 = fft(b8, nf);
            %fftc7 = fft(c7, nf);
            %fftd7 = fft(d7, nf);
            %ffte7 = fft(e7, nf);
            fftoutput = fft(finalOutput, nf);
            
            f = Fs/2*linspace(0,1,nf/2+1);
            
            %f = logspace(0,4,nf/2+1);
            %Attenuation
            %yplot = 10*log10(abs(ffta1(1:nf/2+1)./fftip(1:nf/2+1)));
             
            %Input and outputs
            yplot1 = 50*abs(fftip(1:nf/2+1))/nf;
            %yplot2 = 50*abs(ffta7(1:nf/2+1))/nf;
            %yplot3 = 50*abs(fftb8(1:nf/2+1))/nf;
            %yplot4 = 50*abs(fftc7(1:nf/2+1))/nf;
            %yplot5 = 50*abs(fftd7(1:nf/2+1))/nf;
            %yplot6 = 50*abs(ffte7(1:nf/2+1))/nf;
            yplot7 = 50*abs(fftoutput(1:nf/2+1))/nf;
            
            
            semilogx(app.SpectrumChart,f,yplot1,'g',f,yplot7,'b');
            
            %semilogx(f,yplot1 ,'g'); %Better than plot as with log X axis
            %hold on
            %semilogx(f,yplot2 ,'b'); %Better than plot as with log X axis
            %hold on
            %semilogx(f,yplot3 ,'r'); %Better than plot as with log X axis
            %hold on

            xlabel(app.SpectrumChart,'Frequency (in hertz)');
            ylabel(app.SpectrumChart,'Amplitude');
            title(app.SpectrumChart, ['Magnitude Response',...
                    ' 200Hz@',num2str(round(app.Slider00200Hz.Value,0)),'dB',...
                    ' 750Hz@',num2str(round(app.Slider00750Hz.Value,0)),'dB',...
                    ' 3kHz@',num2str(round(app.Slider03000Hz.Value,0)),'dB',...
                    ' 12kHz@',num2str(round(app.Slider10000Hz.Value,0)),'dB',...
                    ' 25kHz@',num2str(round(app.Slider25000Hz.Value,0)),'dB'...
            ]);
            legend(app.SpectrumChart, 'Input','Output' ); %,'Attenuation')
            
            %title(app.SpectrumChart, 'Spectrum Analysis')
            %xlabel(app.SpectrumChart, 'Frequency')
            %ylabel(app.SpectrumChart, 'Amplitude')
            app.SpectrumChart.XLim = [20 25000];
            
%             
%             %plot(f,2*abs(fftip(1:nf/2+1))/nf);
%             semilogx(f,yplot1,'g');
%             hold on
%             %semilogx(f,yplot2 ,'b'); %Better than plot as with log X axis
%             hold on
%             %semilogx(f,yplot3 ,'r'); %Better than plot as with log X axis
%             hold on
%             %semilogx(f,yplot4 ,'k'); %Better than plot as with log X axis
%             hold on
%             %semilogx(f,yplot5 ,'y'); %Better than plot as with log X axis
%             hold on
%             %semilogx(f,yplot6 ,'c'); %Better than plot as with log X axis
%             hold on
%             semilogx(f,yplot7 ,'r'); %Better than plot as with log X axis
%             hold on
            
            
            %atten = (movmean(yplot2,5) ./ (movmean(yplot1+0.1,5)));
            %semilogx(f,atten,'r'); %Better than plot as with log X axis
%             xlabel('Frequency (in hertz)');
%             ylabel('Amplitude');
%             title(strcat('Magnitude Response (A1 = ',num2str(A1),') A2 = ',num2str(A2),')'));
%             legend('Input','A7','B8', 'C7', 'D7', 'E7', 'Output' ); %,'Attenuation')
             
            switch app.DDownSoundChoice.Value
                case 5
                    loadFile = true;
                        sound(ip,Fs);
                        pause(audio_duration+1);
                        %sound(a7,Fs);
                        %pause(2);
                        %sound(b8,Fs);
                        %pause(2)
                        %sound(c7,Fs);
                        %pause(2)
                        %sound(d7,Fs);
                        %pause(2)
                        %sound(e7,Fs);
                        %pause(2)
                        sound(finalOutput,Fs);
                        sound(ip,Fs);
                        pause(audio_duration+1);
                        %sound(a7,Fs);
                        %pause(2);
                        %sound(b8,Fs);
                        %pause(2)
                        %sound(c7,Fs);
                        %pause(2)
                        %sound(d7,Fs);
                        %pause(2)
                        %sound(e7,Fs);
                        %pause(2)
                        sound(finalOutput,Fs);
            end

        end
        
%             
%         function PlaySound(app)
%             A1 = 75; 
%             % 1/A1 drives ~ the filter cutoff normalized frequency (signal frequency / sampling frequency)
%             % example: 75 -> 1 / 75 * 44.1kHz => 600Hz
%             %           9 -> 1 / 9  * 44.1kHz => 4900Hz
%             A2 = 53;
%             
%             %A1 = 9;
%             %A2 = 7;
%             
%             
%             %setting a frame length greater than A1
%             frame_len = 100;
%             
%             loadFile = false;
%             startFreq = 0;
%             coefFreq = 0;
%             
%             switch app.DDownSoundChoice.Value
%                 case 1
%                     startFreq = 50;
%                     coefFreq = 1.135;
%                     number_of_samples = 44100;
%                 case 2
%                     startFreq = 50;
%                     coefFreq = 1.05;
%                 case 3
%                     startFreq = 1000;
%                     coefFreq = 1.05;
%                 case 4
%                     startFreq = 5000;
%                     coefFreq = 1.05;
%                 case 5
%                     loadFile = true;
%                     
%             end
%             
%             if ( loadFile == true )
%                 %read in audio file mono at 44.1kHz
% 
%                           
%                 % Display uigetfile dialog
%                 filterspec = {'*.wav','All WAV Files'};
%                 [f, p] = uigetfile(filterspec);
%                 
%                 % Make sure user didn't cancel uigetfile dialog
%                 if (ischar(p))
%                    fname = [p f];
%                    %updateimage(app, fname);
%                 else
%                    return;
%                 end
%       
%                 %[ip,Fs] = audioread('/Users/Marc/Documents/MATLAB/sample1.wav',[1,44100]);
%                 [ip,Fs] = audioread(fname,[1,44100]);
%                 numFrames = floor(length(ip)/frame_len);
%                 number_of_samples = numFrames*frame_len;
%                 ip = ip(1:number_of_samples);
%             else
% 
%                 Fs = 44100; %frequency in Hz
%                 audio_duration = 1; % in seconds
%                 number_of_samples = audio_duration * Fs;
%                 numFrames = number_of_samples / frame_len;
%                 
%                 % Building the list of frequency making up the input signal,
%                 %  and the input signal
%                 
%                 ip = zeros(1, number_of_samples );
%                 
%                 freqs = zeros(1, 50);
%                 
%                 for freqid = 1:1:50
%                     curfreq=startFreq*power(coefFreq,freqid-1);
%                     freqs(freqid)=curfreq;
%                     for recId = 1:1:number_of_samples
%                         ip(recId) = ip(recId) + sin(2*pi*curfreq/44100*recId + curfreq) / 50;
%                     end
%                 end
%             end
%             
%             %plot(ip);
%             
%             % setting up the output vectors for the first RRS filter
%             a1 = zeros(1, number_of_samples);
%             a2 = zeros(1, number_of_samples);
%             
%             
%             for frameId = 1:1:numFrames %iterate through frames
%                 frameStartsAt = (frameId-1)*frame_len; %start position of current frame
%                 for recIdInFrame = 1:1:frame_len %iterate through values in current frame
%                     recId = frameStartsAt + recIdInFrame; %absolute value index
%                     if frameId == 1 %avoid errors processing first frame (no history available)           
%                         a1(recId) = ip(recId) * A1;
%                         a2(recId) = ip(recId) * A1 * A2;
%                     else %determining the output value after the first RRS filter
%                         % using actual input from audio file and applying first RRS filter
%                         a1(recId) = (a1(recId-1) + ip(recId) - ip(recId - A1));
%                         a2(recId) = (a2(recId-1) + a1(recId) - a1(recId - A2));
%                     end
%                 end
%             end
%             
%             a1 = a1 / A1;
%             a2 = a2 / ( A1 * A2);
%             
%             nf=2000; %number of points in DTFT
%             
%             fftip = fft( ip ,nf);
%             ffta1 = fft( a1 ,nf);
%             ffta2 = fft( a2 ,nf);
%             
%             f = Fs/2*linspace(0,1,nf/2+1);
%             %f = logspace(0,4,nf/2+1);
%             
%             %Attenuation
%             %yplot = 10*log10(abs(ffta1(1:nf/2+1)./fftip(1:nf/2+1)));
%             
%             %Input and outputs
%             yplot1 = 50*abs(fftip(1:nf/2+1))/nf;
%             yplot2 = 50*abs(ffta1(1:nf/2+1))/nf;
%             yplot3 = 50*abs(ffta2(1:nf/2+1))/nf;
%             
%             %histr = histogram(app.SpectrumChart, im, 'FaceColor',[1 0 0],'EdgeColor', 'none');
%             
%             %plot(app.SpectrumChart,f,2*abs(fftip(1:nf/2+1))/nf);
%             
%             semilogx(app.SpectrumChart,f,yplot1,'g',f,yplot2,'b',f,yplot3,'r');
%             
%             %semilogx(f,yplot1 ,'g'); %Better than plot as with log X axis
%             %hold on
%             %semilogx(f,yplot2 ,'b'); %Better than plot as with log X axis
%             %hold on
%             %semilogx(f,yplot3 ,'r'); %Better than plot as with log X axis
%             %hold on
%             %atten = (movmean(yplot2,5) ./ (movmean(yplot1+0.1,5)));
%             %semilogx(f,atten,'r'); %Better than plot as with log X axis
%             xlabel(app.SpectrumChart,'Frequency (in hertz)');
%             ylabel(app.SpectrumChart,'Amplitude');
%             title(app.SpectrumChart, strcat('Magnitude Response (A1 = ',num2str(A1),') A2 = ',num2str(A2),')'));
%             legend(app.SpectrumChart, 'Input','Output RRS', 'Output DRRS' ); %,'Attenuation')
%             
%             %title(app.SpectrumChart, 'Spectrum Analysis')
%             %xlabel(app.SpectrumChart, 'Frequency')
%             %ylabel(app.SpectrumChart, 'Amplitude')
%             app.SpectrumChart.XLim = [20 25000];
%             %app.SpectrumChart.XTick = [0 1];
%             %app.SpectrumChart.XTickLabel = {'0'; '10'; '100'; '500'; '2000'; '5000'; '20000'};
%             %app.SpectrumChart.Position = [121 215 459 300];
%             
%             %sound(ip,44100);
%             %pause(2);
%             %sound(a1,44100);
%             %pause(2);
%             %sound(a2,44100);
%             
%         end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Value changed function: DDownSoundChoice
        function DDownSoundChoiceValueChanged(app, event)
            if (app.DDownSoundChoice.Value ~= 5) 
               Equalize(app); 
            end

        end

        % Callback function
        function PlayButtonPushed(app, event)
               

        end

        % Value changed function: Slider00200Hz
        function Slider200HzValueChanging(app, event)
            if (app.DDownSoundChoice.Value ~= 5) 
               Equalize(app); 
            end

        end

        % Button pushed function: BtnPlay
        function BtnPlayPushed(app, event)
            %PlaySound(app);
            Equalize(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Slider00200Hz.Value = 0;
            app.Slider00750Hz.Value = 0;
            app.Slider03000Hz.Value = 0;
            app.Slider10000Hz.Value = 0;
            app.Slider25000Hz.Value = 0;
            
            if (app.DDownSoundChoice.Value ~= 5) 
               Equalize(app); 
            end
                        
        end

        % Value changed function: Slider00750Hz
        function Slider00750HzValueChanged(app, event)
            if (app.DDownSoundChoice.Value ~= 5) 
               Equalize(app); 
            end
            
        end

        % Value changed function: Slider03000Hz
        function Slider03000HzValueChanged(app, event)
            if (app.DDownSoundChoice.Value ~= 5) 
                Equalize(app); 
            end
            
        end

        % Value changed function: Slider10000Hz
        function Slider10000HzValueChanged(app, event)
            if (app.DDownSoundChoice.Value ~= 5) 
               Equalize(app); 
            end
            
        end

        % Value changed function: Slider25000Hz
        function Slider25000HzValueChanged(app, event)
            if (app.DDownSoundChoice.Value ~= 5) 
               Equalize(app); 
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Audio Equalizer';
            app.DDownSoundChoice = uidropdown(app.UIFigure);
            app.DDownSoundChoice.Items = {'50 low to high Hz', '50 low Hz', '50 medium Hz', '50 high Hz ', 'Custom file', ''};
            app.DDownSoundChoice.ItemsData = [1 2 3 4 5];
            app.DDownSoundChoice.ValueChangedFcn = createCallbackFcn(app, @DDownSoundChoiceValueChanged, true);
            app.DDownSoundChoice.Value = 1;
            app.InputSoundLabel = uilabel(app.UIFigure);
            app.InputSoundLabel.Text = 'Input Sound';
            app.Hz200HzLabel = uilabel(app.UIFigure);
            app.Hz200HzLabel.Text = '0Hz-200Hz';
            app.Slider00200Hz = uislider(app.UIFigure);
            app.Slider00200Hz.Limits = [-30 30];
            app.Slider00200Hz.ValueChangedFcn = createCallbackFcn(app, @Slider200HzValueChanging, true);
            app.BtnPlay = uibutton(app.UIFigure, 'push');
            app.BtnPlay.ButtonPushedFcn = createCallbackFcn(app, @BtnPlayPushed, true);
            app.BtnPlay.Text = 'Go!';
            app.Hz750HzLabel_2 = uilabel(app.UIFigure);
            app.Hz750HzLabel_2.Text = '200Hz-750Hz';
            app.Slider00750Hz = uislider(app.UIFigure);
            app.Slider00750Hz.Limits = [-30 30];
            app.Slider00750Hz.ValueChangedFcn = createCallbackFcn(app, @Slider00750HzValueChanged, true);
            app.Hz3kHzLabel = uilabel(app.UIFigure);
            app.Hz3kHzLabel.Text = '750Hz-3kHz';
            app.Slider03000Hz = uislider(app.UIFigure);
            app.Slider03000Hz.Limits = [-30 30];
            app.Slider03000Hz.ValueChangedFcn = createCallbackFcn(app, @Slider03000HzValueChanged, true);
            app.kHz10kHzLabel = uilabel(app.UIFigure);
            app.kHz10kHzLabel.Text = '3kHz-10kHz';
            app.Slider10000Hz = uislider(app.UIFigure);
            app.Slider10000Hz.Limits = [-30 30];
            app.Slider10000Hz.ValueChangedFcn = createCallbackFcn(app, @Slider10000HzValueChanged, true);
            app.kHz25kHzLabel = uilabel(app.UIFigure);
            app.kHz25kHzLabel.Text = '10kHz-25kHz';
            app.Slider25000Hz = uislider(app.UIFigure);
            app.Slider25000Hz.Limits = [-30 30];
            app.Slider25000Hz.ValueChangedFcn = createCallbackFcn(app, @Slider25000HzValueChanged, true);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SpectrumChart = uiaxes(app.UIFigure);
            title(app.SpectrumChart, 'Spectrum Analysis')
            xlabel(app.SpectrumChart, 'Frequency')
            ylabel(app.SpectrumChart, 'Signal Amplitude')
            app.SpectrumChart.XLim = [0 255];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AudioEqualizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end